classdef CellCountingGUI_rev4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellCountingGUIUIFigure        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LoadImagesTab                  matlab.ui.container.Tab
        ReferenceChannelPanel          matlab.ui.container.Panel
        LoadBlueButton                 matlab.ui.control.Button
        BlueImageLabel                 matlab.ui.control.Label
        SelectBoundingBoxButton        matlab.ui.control.Button
        ConfirmBoundingBoxButton       matlab.ui.control.Button
        SelectCenterpointButton        matlab.ui.control.Button
        MapExclusionButton             matlab.ui.control.Button
        AdditionalChannelsPanel        matlab.ui.container.Panel
        LoadGreenButton                matlab.ui.control.Button
        GreenImageLabel                matlab.ui.control.Label
        LoadRedButton                  matlab.ui.control.Button
        RedImageLabel                  matlab.ui.control.Label
        ClearAllButton                 matlab.ui.control.Button
        SaveWorkspaceButton            matlab.ui.control.Button
        LoadWorkspaceButton            matlab.ui.control.Button
        ConfirmImagesButton            matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        RefChannelTab                  matlab.ui.container.Tab
        BlueThresholdPass1Label        matlab.ui.control.Label
        BlueThresholdPass1Slider       matlab.ui.control.Slider
        MinBlueSizePixelsSliderLabel   matlab.ui.control.Label
        MinBlueSizePixelsSlider        matlab.ui.control.Slider
        AddROIsButton                  matlab.ui.control.Button
        RemoveBlueROIsButton           matlab.ui.control.Button
        BlueOnlyLabel                  matlab.ui.control.Label
        PopulationLabel                matlab.ui.control.Label
        RotationPanel                  matlab.ui.container.Panel
        DegreeLabel                    matlab.ui.control.Label
        RotateValue                    matlab.ui.control.NumericEditField
        ProcessBlueButton              matlab.ui.control.Button
        CommitBlueROIsButton           matlab.ui.control.Button
        ChangeBlueRangesButton         matlab.ui.control.Button
        UpdateBlueDisplayButton        matlab.ui.control.Button
        SplitROIsButton                matlab.ui.control.Button
        ColorizeSwitchLabel            matlab.ui.control.Label
        ColorizeBlueSwitch             matlab.ui.control.Switch
        AddArbitraryROIsButton         matlab.ui.control.Button
        MaxBlueSizePixelsSliderLabel   matlab.ui.control.Label
        MaxBlueSizePixelsSlider        matlab.ui.control.Slider
        BlueThresholdPass2SliderLabel  matlab.ui.control.Label
        BlueThresholdPass2Slider       matlab.ui.control.Slider
        AdditionalChannelsTab          matlab.ui.container.Tab
        GreenThresholdSliderLabel      matlab.ui.control.Label
        GreenThresholdSlider           matlab.ui.control.Slider
        GreenSizePixelsSliderLabel     matlab.ui.control.Label
        GreenSizePixelsSlider          matlab.ui.control.Slider
        RedThresholdLabel              matlab.ui.control.Label
        RedThresholdSlider             matlab.ui.control.Slider
        RedSizePixelsSliderLabel       matlab.ui.control.Label
        RedSizePixelsSlider            matlab.ui.control.Slider
        GreenThresholdSwitch           matlab.ui.control.Switch
        RedThresholdSwitch             matlab.ui.control.Switch
        PopulationBlueGreenRedLabel    matlab.ui.control.Label
        ExportROIsButton               matlab.ui.control.Button
        ToggleROIButton                matlab.ui.control.Button
        RemoveROIsButton               matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        BlueLabel                      matlab.ui.control.Label
        GreenLabel                     matlab.ui.control.Label
        DisplayControlsPanel           matlab.ui.container.Panel
        UpdateDisplayButton            matlab.ui.control.Button
        ShowChannelGreenSwitch         matlab.ui.control.Switch
        ShowChannelRedSwitch           matlab.ui.control.Switch
        ShowROIGreenSwitch             matlab.ui.control.Switch
        ShowROIRedSwitch               matlab.ui.control.Switch
        BlueDisplayLabel               matlab.ui.control.Label
        GreenDisplayLabel              matlab.ui.control.Label
        RedDisplayLabel                matlab.ui.control.Label
        ShowROIsSwitch_2Label          matlab.ui.control.Label
        ShowROIBlueSwitch              matlab.ui.control.Switch
        ShowChannelSwitchLabel         matlab.ui.control.Label
        ShowChannelBlueSwitch          matlab.ui.control.Switch
        RedLabel                       matlab.ui.control.Label
        ChangeRangesButton             matlab.ui.control.Button
        ColorizeSwitch_2Label          matlab.ui.control.Label
        ColorizeAllSwitch              matlab.ui.control.Switch
    end

%%%%%%%%% Cell Counting GUI by Jonathan Krynitsky (NIH) %%%%%%%%%%%%%%%%
    properties (Access = private)
        
        RectangleCrop = [];
        RectangleCropBoundingBox = [];
        RectangleCropBoundingBoxOutline = [];
        RectangleCropBoundingBoxConvexImage = [];
        RectangleCropBoundingBoxConvexImageOutline = [];
        CenterPoint = [];
        
        % Blue image variables
        Blue_Image = [];
        Blue_Image_Cropped = [];
        Blue_Image_Filename = '';
        Blue_Image_Path = '';
        
        % Green image variables
        Green_Image = [];
        Green_Image_Cropped = [];
        Green_Image_Filename = '';
        Green_Image_Path = '';
        
        % Red image variables
        Red_Image = [];
        Red_Image_Cropped = [];
        Red_Image_Filename = '';
        Red_Image_Path = '';
        
        
        Blue_ROI = []; % Container for the current ROI
        Blue_ROI_Outlines = [];
        Blue_ROI_Filename = '';        
        Blue_ROI_Path = '';
        Selected_Blue_ROIs = []; % Container for selected ROIs
        Selected_Blue_ROI_Outlines = [];
        
        Green_ROI = []; % Container for the current ROI
        Green_ROI_Filename = '';        
        Green_ROI_Path = '';
        Selected_Green_ROIs = []; % Container for selected ROIs
        Selected_Green_ROI_Outlines = [];
        
        Red_ROI = []; % Container for the current ROI
        Red_ROI_Filename = '';        
        Red_ROI_Path = '';
        Selected_Red_ROIs = []; % Container for selected ROIs
        Selected_Red_ROI_Outlines = [];
        
        CurrentDisplayImg = -1; % -1=Unset, 0=Blue, 1=Green, 2=Red
        
        LastImagePath = ''; % Variable to save the last path for user friendly file selection
        
        DisplayImage = []; % Current RGB display image 
        
        ImageFrame = []; % Variables to store the object for the image frame and figure
        ImageFigure = [];
    end
    
    methods (Access = private)
        
        function updateIndices(app)
            numBlue = numel(unique(app.Selected_Blue_ROIs))-1;
            
            numGreen = numel(unique(app.Selected_Green_ROIs))-1;
            
            numRed = numel(unique(app.Selected_Red_ROIs))-1;
                        
            app.BlueLabel.Text = num2str(numBlue+numGreen+numRed);
            app.BlueOnlyLabel.Text = num2str(numBlue+numGreen+numRed);
            app.GreenLabel.Text = num2str(numGreen);
            app.RedLabel.Text = num2str(numRed);
           
        end
        
        function newCoord = translateCoord(app, Coord)
            alpha = -1*app.RotateValue.Value;   % angle for rotation
            RotMatrix = [cosd(alpha) -sind(alpha); sind(alpha) cosd(alpha)]; 
            ImCenterB = (size(app.Blue_Image_Cropped)/2)';         % Center of the main image
            ImCenterA = (size(app.DisplayImage(:,:,1))/2)';  % Center of the transformed image
            newCoord = RotMatrix*(Coord-ImCenterA)+ImCenterB;
        end
        
        function updateImageConfiguration(app)
            % Function to update the display image for loading/cropping
            
            if app.CurrentDisplayImg > -1 && app.CurrentDisplayImg < 3 
                if ~(size(app.DisplayImage,1) == size(app.Blue_Image,1) &&  size(app.DisplayImage,2) == size(app.Blue_Image,2))
                    app.DisplayImage = uint8(zeros(size(app.Blue_Image,1), size(app.Blue_Image,2), 3));                    
                end
            end
            
            if app.CurrentDisplayImg == -1
                %Do nothing
                return;
            elseif app.CurrentDisplayImg == 0
                % Display with Blue Image
                app.DisplayImage(:,:,1) = app.Blue_Image*.6;
                app.DisplayImage(:,:,2) = app.Blue_Image*.6;
                app.DisplayImage(:,:,3) = app.Blue_Image;
            elseif app.CurrentDisplayImg == 1
                % Display with Green Image
                app.DisplayImage(:,:,1) = app.Green_Image*.6;
                app.DisplayImage(:,:,2) = app.Green_Image;
                app.DisplayImage(:,:,3) = app.Green_Image*.6;
            elseif app.CurrentDisplayImg == 2
                % Display with Red Image
                app.DisplayImage(:,:,1) = app.Red_Image;
                app.DisplayImage(:,:,2) = app.Red_Image*.6;
                app.DisplayImage(:,:,3) = app.Red_Image*.6;
            elseif app.CurrentDisplayImg == 3
                % Display with Blue Image
                if ~(size(app.DisplayImage,1) == size(app.Blue_Image_Cropped,1) &&  size(app.DisplayImage,2) == size(app.Blue_Image_Cropped,2))
                    app.DisplayImage = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2), 3));
                end
                app.DisplayImage(:,:,1) = app.Blue_Image_Cropped*.6 + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
                app.DisplayImage(:,:,2) = app.Blue_Image_Cropped*.6 + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
                app.DisplayImage(:,:,3) = app.Blue_Image_Cropped + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
                if ~isempty(app.CenterPoint)
                    app.DisplayImage = insertShape(app.DisplayImage, 'FilledCircle', [app.CenterPoint round(size(app.DisplayImage, 1)/100)],'color','yellow');
                end
            end
            
            if ~isempty(app.RectangleCropBoundingBoxOutline) && app.CurrentDisplayImg > -1 && app.CurrentDisplayImg < 3
                app.DisplayImage(:,:,1) = app.DisplayImage(:,:,1) + 200*uint8(app.RectangleCropBoundingBoxOutline);
                app.DisplayImage(:,:,2) = app.DisplayImage(:,:,2) + 200*uint8(app.RectangleCropBoundingBoxOutline);
                app.DisplayImage(:,:,3) = app.DisplayImage(:,:,3) + 200*uint8(app.RectangleCropBoundingBoxOutline);
            end
            
            if isempty(app.ImageFrame)
                % Create new figure/frame if empty
                app.ImageFigure = figure('Name', 'Image Setup Display', 'NumberTitle', 'off');
                app.ImageFrame = imshow(app.DisplayImage,'InitialMagnification','fit','Border','tight');
            else
                try
                    % Try to update figure/frame if available...
                    set(app.ImageFrame, 'CData', app.DisplayImage);
                catch
                    % Create new figure/frame if closed (i.e. error above)
                    app.ImageFigure = figure('Name', 'Image Setup Display', 'NumberTitle', 'off');
                    app.ImageFrame = imshow(app.DisplayImage,'InitialMagnification','fit','Border','tight');
                end
            end
            
            figure(app.CellCountingGUIUIFigure); % used to reset focus to app
        end
        
        
        function updateBlueImage(app)
            % Function to update the display image for processing functions, called very often
            
            if size(app.Blue_Image_Cropped,1) == 0
                %Do nothing, no blue image loaded so not enough data
                return;
            end
            
            if ~(size(app.DisplayImage,1) == size(app.Blue_Image_Cropped,1) &&  size(app.DisplayImage,2) == size(app.Blue_Image_Cropped,2))
                app.DisplayImage = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2), 3));                    
            end
            
            red_channel = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2)));
            green_channel = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2)));
            blue_channel = app.Blue_Image_Cropped;
            
            if ~isempty(app.Selected_Blue_ROI_Outlines)
                red_channel(app.Selected_Blue_ROI_Outlines) = 0;
                green_channel(app.Selected_Blue_ROI_Outlines) = 125;
                blue_channel(app.Selected_Blue_ROI_Outlines) = 255;
            end
            
            app.DisplayImage(:,:,1) = red_channel + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
            app.DisplayImage(:,:,2) = green_channel + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
            app.DisplayImage(:,:,3) = blue_channel + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
            
            if ~isempty(app.Selected_Blue_ROIs) && strcmp(app.ColorizeBlueSwitch.Value, 'On')
                colorize_rgb = label2rgb(app.Selected_Blue_ROIs,'jet', [0 0 0], 'shuffle');
                app.DisplayImage(colorize_rgb>0) = colorize_rgb(colorize_rgb>0);
            end
            
            % Rotate image based on set value
            app.DisplayImage = imrotate(app.DisplayImage, app.RotateValue.Value, 'bilinear', 'loose');
            
            if isempty(app.ImageFrame)
                % Create new figure/frame if empty
                app.ImageFigure = figure('Name', 'Image Display', 'NumberTitle', 'off');
                app.ImageFrame = imshow(app.DisplayImage,'InitialMagnification','fit','Border','tight');
            else
                try
                    % Try to update figure/frame if available...
                    set(app.ImageFrame, 'CData', app.DisplayImage);
                catch
                    % Create new figure/frame if closed (i.e. error above)
                    app.ImageFigure = figure('Name', 'Image Display', 'NumberTitle', 'off');
                    app.ImageFrame = imshow(app.DisplayImage,'InitialMagnification','fit','Border','tight');
                end
            end
            
            figure(app.CellCountingGUIUIFigure); % used to reset focus to app
        end
               
        function updateImage(app)
            % Function to update the display image for processing functions, called very often
            
            if size(app.Blue_Image_Cropped,1) == 0
                %Do nothing, no blue image loaded so not enough data
                return;
            end
            
            if ~(size(app.DisplayImage,1) == size(app.Blue_Image_Cropped,1) &&  size(app.DisplayImage,2) == size(app.Blue_Image_Cropped,2))
                app.DisplayImage = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2), 3));                    
            end
            
            if strcmp(app.ShowChannelRedSwitch.Value, 'On') && ~isempty(app.Red_Image_Cropped)
                red_channel = app.Red_Image_Cropped;
            else 
                red_channel = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2)));
            end
            if strcmp(app.ShowChannelGreenSwitch.Value, 'On') && ~isempty(app.Green_Image_Cropped)
                green_channel = app.Green_Image_Cropped;
            else 
                green_channel = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2)));
            end
            if strcmp(app.ShowChannelBlueSwitch.Value, 'On') && ~isempty(app.Blue_Image_Cropped)
                blue_channel = app.Blue_Image_Cropped;
            else 
                blue_channel = uint8(zeros(size(app.Blue_Image_Cropped,1), size(app.Blue_Image_Cropped,2)));
            end
            
            if strcmp(app.ShowROIBlueSwitch.Value, 'On') && ~isempty(app.Selected_Blue_ROI_Outlines)
                red_channel(app.Selected_Blue_ROI_Outlines) = 0;
                green_channel(app.Selected_Blue_ROI_Outlines) = 125;
                blue_channel(app.Selected_Blue_ROI_Outlines) = 255;
            end
            if strcmp(app.ShowROIRedSwitch.Value, 'On') && ~isempty(app.Selected_Red_ROI_Outlines)
                red_channel(app.Selected_Red_ROI_Outlines) = 255;
                green_channel(app.Selected_Red_ROI_Outlines) = 0;
                blue_channel(app.Selected_Red_ROI_Outlines) = 125;
            end
            if strcmp(app.ShowROIGreenSwitch.Value, 'On') && ~isempty(app.Selected_Green_ROI_Outlines)
                red_channel(app.Selected_Green_ROI_Outlines) = 125;
                green_channel(app.Selected_Green_ROI_Outlines) = 255;
                blue_channel(app.Selected_Green_ROI_Outlines) = 0;
            end
            
            app.DisplayImage(:,:,1) = red_channel + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
            app.DisplayImage(:,:,2) = green_channel + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
            app.DisplayImage(:,:,3) = blue_channel + 200*uint8(app.RectangleCropBoundingBoxConvexImageOutline);
            
            if strcmp(app.ShowROIRedSwitch.Value, 'On') && ~isempty(app.Selected_Red_ROI_Outlines) && strcmp(app.ColorizeAllSwitch.Value, 'On')
                colorize_rgb = label2rgb(app.Selected_Red_ROIs,'jet', [0 0 0], 'shuffle');
                app.DisplayImage(colorize_rgb>0) = colorize_rgb(colorize_rgb>0);
            end
            
            % Rotate image based on set value
            app.DisplayImage = imrotate(app.DisplayImage, app.RotateValue.Value, 'bilinear', 'loose');
            
            if isempty(app.ImageFrame)
                % Create new figure/frame if empty
                app.ImageFigure = figure('Name', 'Image Display', 'NumberTitle', 'off');
                app.ImageFrame = imshow(app.DisplayImage,'InitialMagnification','fit','Border','tight');
            else
                try
                    % Try to update figure/frame if available...
                    set(app.ImageFrame, 'CData', app.DisplayImage);
                catch
                    % Create new figure/frame if closed (i.e. error above)
                    app.ImageFigure = figure('Name', 'Image Display', 'NumberTitle', 'off');
                    app.ImageFrame = imshow(app.DisplayImage,'InitialMagnification','fit','Border','tight');
                end
            end
            
            figure(app.CellCountingGUIUIFigure); % used to reset focus to app
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadBlueButton
        function LoadBlueButtonPushed(app, event)
            % Display uigetfile dialog
            if isempty(app.LastImagePath)
                [f, p] = uigetfile('*tif', 'Select a Blue Image File to Open');
            else 
                [f, p] = uigetfile([app.LastImagePath '\*.tif'], 'Select a Blue Image File to Open');
            end
            
            % Make sure user didn't cancel uigetfile dialog
            if ischar(p)
               % Load Blue image and set up variables
               tempImg = imread([p f]);
               app.Blue_Image = tempImg(:,:,3);
               app.Blue_Image_Filename = f;
               app.Blue_Image_Path = p;
               app.BlueImageLabel.Text = f;
               
               app.LastImagePath = p;
               
               if sum(sum(tempImg(:,:,1))) > 0 && sum(sum(tempImg(:,:,2))) > 0 
                   % 3 layer image with all data
                   app.Red_Image = tempImg(:,:,1);
                   app.Red_Image_Filename = f;
                   app.Red_Image_Path = p;
                   app.RedImageLabel.Text = f;
                   
                   app.Green_Image = tempImg(:,:,2);
                   app.Green_Image_Filename = f;
                   app.Green_Image_Path = p;
                   app.GreenImageLabel.Text = f;
               end
               
               app.SelectBoundingBoxButton.Enable = true;
               app.CurrentDisplayImg = 0;
               updateImageConfiguration(app);
               
            else
               figure(app.CellCountingGUIUIFigure)
            end
        end

        % Button pushed function: LoadGreenButton
        function LoadGreenButtonPushed(app, event)
            % Display uigetfile dialog
            if isempty(app.LastImagePath)
                [f, p] = uigetfile('*tif', 'Select a Green Image File to Open');
            else 
                [f, p] = uigetfile([app.LastImagePath '\*.tif'], 'Select a Green Image File to Open');
            end
            
            % Make sure user didn't cancel uigetfile dialog
            if ischar(p)
               % Load Green image and set up variables
               tempImg = imread([p f]);
               app.Green_Image = tempImg(:,:,2);
               app.Green_Image_Filename = f;
               app.Green_Image_Path = p;
               app.GreenImageLabel.Text = f;
               
               if ~isempty(app.RectangleCropBoundingBox)
                   app.Green_Image_Cropped = imcrop(app.Green_Image, app.RectangleCropBoundingBox);
               end
               
               app.LastImagePath = p;
               
               app.CurrentDisplayImg = 1;
               updateImageConfiguration(app);
            else
               figure(app.CellCountingGUIUIFigure)
            end
        end

        % Button pushed function: LoadRedButton
        function LoadRedButtonPushed(app, event)
            % Display uigetfile dialog
            if isempty(app.LastImagePath)
                [f, p] = uigetfile('*tif', 'Select a Red Image File to Open');
            else 
                [f, p] = uigetfile([app.LastImagePath '\*.tif'], 'Select a Red Image File to Open');
            end
            
            % Make sure user didn't cancel uigetfile dialog
            if ischar(p)
               % Load Red image and set up variables
               tempImg = imread([p f]);
               app.Red_Image = tempImg(:,:,1);
               app.Red_Image_Filename = f;
               app.Red_Image_Path = p;
               app.RedImageLabel.Text = f;
               
               if ~isempty(app.RectangleCropBoundingBox)
                   app.Red_Image_Cropped = imcrop(app.Red_Image, app.RectangleCropBoundingBox);
               end
               
               app.LastImagePath = p;
               
               app.CurrentDisplayImg = 2;
               updateImageConfiguration(app);
            else
               figure(app.CellCountingGUIUIFigure)
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            if ~isempty(app.Blue_Image_Cropped) % Only pocess if blue image is loaded
                
                app.ProcessButton.BackgroundColor = [.91 0 0]; % Change button color to red while processing
                
                % Reset Blue Results to previous selection
                blue_bw = app.Blue_ROI>0;
                app.Selected_Blue_ROIs = app.Blue_ROI;
                app.Selected_Blue_ROI_Outlines = app.Blue_ROI_Outlines;
                temp_bw = false(size(blue_bw));
                
                if ~isempty(app.Red_Image_Cropped) && strcmp(app.RedThresholdSwitch.Value, 'On') 
                    procRedImage = app.Red_Image_Cropped;
                    procRedImage = medfilt2(procRedImage, [2 2]);  % median filt to remove nois
                    red_bw = procRedImage > app.RedThresholdSlider.Value; % threshold and area filter
                    red_bw = bwareaopen(imfill(red_bw,'holes'), round(app.RedSizePixelsSlider.Value)); % fill holes and area filter

                    % Find and select blues with red
                    red_idx = unique(app.Selected_Blue_ROIs(red_bw>0));
                    red_idx(red_idx==0) = [];
                    
                    app.Selected_Red_ROIs = app.Selected_Blue_ROIs;
                    app.Selected_Red_ROIs(~ismember(app.Selected_Blue_ROIs, red_idx)) = 0; %remove non red
                    app.Selected_Red_ROIs(~red_bw) = 0; % remove non red bw
                    app.Red_ROI = app.Selected_Red_ROIs > 0;
                    app.Selected_Red_ROI_Outlines = boundarymask(app.Red_ROI);
                    
                    temp_bw(ismember(app.Selected_Blue_ROIs, red_idx)) = true;
                    blue_bw(temp_bw) = 0;
                    app.Selected_Blue_ROIs(temp_bw) = 0;
                    app.Selected_Blue_ROI_Outlines(imdilate(temp_bw, strel('disk', 3))) = 0;
                    
                    
                else
                    % No red image, make blank ROI variables
                    app.Red_ROI = false(size(blue_bw));
                    app.Selected_Red_ROIs = false(size(blue_bw));
                    app.Selected_Red_ROI_Outlines = false(size(blue_bw));
                end
                
                if ~isempty(app.Green_Image_Cropped) && strcmp(app.GreenThresholdSwitch.Value, 'On')
                    % Green does not work very well right now, will improve
                    % in future!
                    
                    temp_bw(:) = false;
                    procGreenImage = app.Green_Image_Cropped;
                    procGreenImage = medfilt2(procGreenImage, [2 2]);  % median filt to remove noise
                    green_bw = procGreenImage > app.GreenThresholdSlider.Value; % threshold Green
                    green_bw = bwareaopen(imfill(green_bw,'holes'), round(app.GreenSizePixelsSlider.Value)); % fill holes and area filter
                    
                    % Label green
                    green_lbl = bwlabel(green_bw);
                    
                    % Find green idx where blue exists
                    green_idx = unique(green_lbl(app.Selected_Blue_ROIs>0));
                    green_idx(green_idx==0) = [];
                    
                    % Remove green idx where blue does not exist
                    green_lbl(~ismember(green_lbl, green_idx)) = 0;
                    green_bw = green_lbl>0;
                    
                    % remove accepted green from blue
                    blue_idx = unique(app.Selected_Blue_ROIs(green_bw>0));
                    blue_idx(blue_idx==0) = [];
                    temp_bw(ismember(app.Selected_Blue_ROIs, blue_idx)) = true;
                    app.Selected_Blue_ROIs(temp_bw) = 0;
                    app.Selected_Blue_ROI_Outlines(imdilate(temp_bw, strel('disk', 3))) = 0;
                    
                    
                    % Set up Green ROI variables
                    app.Green_ROI = green_bw;
                    app.Selected_Green_ROIs = bwlabel(green_bw);
                    app.Selected_Green_ROI_Outlines = boundarymask(green_bw);
                else
                    % No green image, make blank ROI variables
                    app.Green_ROI = false(size(blue_bw));
                    app.Selected_Green_ROIs = false(size(blue_bw));
                    app.Selected_Green_ROI_Outlines = false(size(blue_bw));
                end
                
                updateIndices(app);
                
                updateImage(app);
                
                app.ProcessButton.BackgroundColor = [0 .91 0]; % Change process button color back to green
                app.ExportROIsButton.BackgroundColor = [0 .91 0]; % Change export button color to green
            else
                msgbox('Blue image must first be loaded before processing!','Error');
            end
            
        end

        % Button pushed function: RemoveBlueROIsButton
        function RemoveBlueROIsButtonPushed(app, event)
            
            if isempty(app.Selected_Blue_ROIs)
                msgbox('Images must first be processed before editing ROIs','Error');
                return;
            end
            
            updateBlueImage(app);
            
            % Change figure title to remove instructions
            app.ImageFigure.Name = 'Remove Mode - Left click to remove ROIs. Right click when done.';
            
            % Loop through user inputs and remove selected ROIs accordingly
            UserSelection = [0 0];
            while size(UserSelection, 1) > 0
                [UserSelection(1), UserSelection(2), button] = ginputYellow(1);
                
                if button == 3
                    break;
                end
                
                if size(UserSelection, 1) > 0
                    UserSelection = round(translateCoord(app, [UserSelection(2); UserSelection(1)]));
                    
                    if app.Selected_Blue_ROIs(UserSelection(1), UserSelection(2)) > 0
                        % Remove ROI Masks
                        UserSelectionIdx = app.Selected_Blue_ROIs(UserSelection(1), UserSelection(2));
                        temp_blue = app.Selected_Blue_ROIs == UserSelectionIdx;
                        app.Selected_Blue_ROIs(temp_blue) = 0;
                        app.Selected_Blue_ROI_Outlines = xor(app.Selected_Blue_ROI_Outlines, boundarymask(temp_blue));
                                          
                        updateBlueImage(app);
                    end
                end
            end
            
            updateIndices(app);
            
            % Reset figure name and update image
            app.ImageFigure.Name = 'Image Display';
            updateBlueImage(app);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            % Reset all variables to empty/default state
            app.ProcessBlueButton.BackgroundColor = [.91 0 0]; % Change process button color to red
            app.ConfirmImagesButton.BackgroundColor = [.91 0 0];
            app.ProcessButton.BackgroundColor = [.91 0 0];
            app.ExportROIsButton.BackgroundColor = [.91 0 0];
            app.CommitBlueROIsButton.BackgroundColor = [.91 0 0];
            
            app.SelectBoundingBoxButton.Enable = false;
            app.ConfirmBoundingBoxButton.Enable = false;
            app.SelectCenterpointButton.Enable = false;
            
            app.BlueOnlyLabel.Text = 'Blue Only';
            app.GreenLabel.Text = 'Green';
            app.RedLabel.Text = 'Red';
            
            app.RectangleCrop = [];
            app.RectangleCropBoundingBox = [];
            app.RectangleCropBoundingBoxOutline = [];
            app.RectangleCropBoundingBoxConvexImage = [];
            app.CenterPoint = [];
            
            % Blue image variables
            app.Blue_Image = [];
            app.Blue_Image_Cropped = [];
            app.Blue_Image_Filename = '';
            app.Blue_Image_Path = '';
            
            % Green image variables
            app.Green_Image = [];
            app.Green_Image_Cropped = [];
            app.Green_Image_Filename = '';
            app.Green_Image_Path = '';
            
            % Red image variables
            app.Red_Image = [];
            app.Red_Image_Cropped = [];
            app.Red_Image_Filename = '';
            app.Red_Image_Path = '';
            
            app.Blue_ROI = []; % Container for the current ROI
            app.Blue_ROI_Outlines = [];
            app.Blue_ROI_Filename = '';        
            app.Blue_ROI_Path = '';
            app.Selected_Blue_ROIs = []; % Container for selected ROIs
            app.Selected_Blue_ROI_Outlines = [];
            
            app.Green_ROI = []; % Container for the current ROI
            app.Green_ROI_Filename = '';        
            app.Green_ROI_Path = '';
            app.Selected_Green_ROIs = []; % Container for selected ROIs
            app.Selected_Green_ROI_Outlines = [];
            
            app.Red_ROI = []; % Container for the current ROI
            app.Red_ROI_Filename = '';        
            app.Red_ROI_Path = '';
            app.Selected_Red_ROIs = []; % Container for selected ROIs
            app.Selected_Red_ROI_Outlines = [];
            
            app.DisplayImage = []; % Current RGB display image 
            
            app.ImageFrame = []; % Variables to store the object for the image frame and figure
            app.ImageFigure = [];
            
            app.CurrentDisplayImg = -1; % -1=Unset, 0=Blue, 1=Green, 2=Red, 3=Blue Cropped
            
            app.GreenImageLabel.Text = 'Green Image';
            app.RedImageLabel.Text = 'Red Image';
            app.BlueImageLabel.Text = 'Blue Image';
            
            app.RotateValue.Value = 0;
            
            close gcf;
            app.ImageFrame = [];
            
            figure(app.CellCountingGUIUIFigure); % reset focus to GUI
        end

        % Button pushed function: UpdateDisplayButton
        function UpdateDisplayButtonPushed(app, event)
            updateImage(app);
        end

        % Button pushed function: ToggleROIButton
        function ToggleROIButtonPushed(app, event)
            
            % Check if ROIs are processed
            if isempty(app.Selected_Green_ROIs)
                msgbox('Images must first be processed before editing ROIs','Error');
                return;
            end
            
            updateImage(app);
            
            % Change figure title to toggle instructions
            app.ImageFigure.Name = 'Toggle Mode - Left click to toggle ROIs. Right click when done.';
            
            % Loop through user inputs and change selected ROIs accordingly
            UserSelection = [0 0];
            while size(UserSelection, 1) > 0
                [UserSelection(1), UserSelection(2), button] = ginputYellow(1);
                
                if button == 3
                    break;
                end
                
                if size(UserSelection, 1) > 0
                    UserSelection = round(translateCoord(app, [UserSelection(2); UserSelection(1)]));
                    
                    if app.Selected_Blue_ROIs(UserSelection(1), UserSelection(2)) > 0  
                        % Flip Blue to Green
                        UserSelectionIdx = app.Selected_Blue_ROIs(UserSelection(1), UserSelection(2));
                        app.Selected_Green_ROIs(app.Selected_Blue_ROIs == UserSelectionIdx) = max(max(app.Selected_Green_ROIs))+1;
                        app.Selected_Green_ROI_Outlines = boundarymask(app.Selected_Green_ROIs>0);
                        
                        temp_blue = app.Selected_Blue_ROIs == UserSelectionIdx;
                        app.Selected_Blue_ROIs(temp_blue) = 0;
                        app.Selected_Blue_ROI_Outlines = xor(app.Selected_Blue_ROI_Outlines, boundarymask(temp_blue));
                        
                        updateImage(app);
                    elseif app.Selected_Green_ROIs(UserSelection(1), UserSelection(2)) > 0  
                        % Flip Green to Red
                        UserSelectionIdx = app.Selected_Green_ROIs(UserSelection(1), UserSelection(2));
                        app.Selected_Red_ROIs(app.Selected_Green_ROIs == UserSelectionIdx) = max(max(app.Selected_Red_ROIs))+1;
                        app.Selected_Red_ROI_Outlines = boundarymask(app.Selected_Red_ROIs>0);
                        
                        app.Selected_Green_ROIs(app.Selected_Green_ROIs == UserSelectionIdx) = 0;
                        app.Selected_Green_ROI_Outlines = boundarymask(app.Selected_Green_ROIs>0);
                        
                        updateImage(app);
                    elseif ~isempty(app.Selected_Red_ROIs) && app.Selected_Red_ROIs(UserSelection(1), UserSelection(2)) > 0
                        % Flip Red to Blue
                        UserSelectionIdx = app.Selected_Red_ROIs(UserSelection(1), UserSelection(2));
                        temp_blue = app.Selected_Red_ROIs == UserSelectionIdx;
                        app.Selected_Blue_ROIs(temp_blue) = max(max(app.Selected_Blue_ROIs))+1;
                        app.Selected_Blue_ROI_Outlines = or(app.Selected_Blue_ROI_Outlines, boundarymask(temp_blue));
                        
                        app.Selected_Red_ROIs(app.Selected_Red_ROIs == UserSelectionIdx) = 0;
                        app.Selected_Red_ROI_Outlines = boundarymask(app.Selected_Red_ROIs>0);
                        
                        updateImage(app);
                    end
                end
            end
            
            updateIndices(app);
            
            % Reset figure name and update image
            app.ImageFigure.Name = 'Image Display';
            updateImage(app);
        end

        % Button pushed function: ChangeRangesButton
        function ChangeRangesButtonPushed(app, event)
            prompt = {'Min Green Threshold:','Max Green Threshold:','Min Green Size:','Max Green Size:','Min Red Threshold:','Max Red Threshold:','Min Red Size:','Max Red Size:'};
            dlgtitle = 'Enter New Threshold Range Values';
            dims = [1 60];
            definput = {num2str(app.GreenThresholdSlider.Limits(1)),num2str(app.GreenThresholdSlider.Limits(2)),num2str(app.GreenSizePixelsSlider.Limits(1)),num2str(app.GreenSizePixelsSlider.Limits(2)),num2str(app.RedThresholdSlider.Limits(1)),num2str(app.RedThresholdSlider.Limits(2)),num2str(app.RedSizePixelsSlider.Limits(1)),num2str(app.RedSizePixelsSlider.Limits(2))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if ~isempty(answer)
                try
                    % Try to change limits
                    app.GreenThresholdSlider.Limits = [str2num(answer{1}) str2num(answer{2})];
                    app.GreenSizePixelsSlider.Limits = [str2num(answer{3}) str2num(answer{4})];
                    app.RedThresholdSlider.Limits = [str2num(answer{5}) str2num(answer{6})];
                    app.RedSizePixelsSlider.Limits = [str2num(answer{7}) str2num(answer{8})];
                catch
                    % Reset limits and show error message
                    app.GreenThresholdSlider.Limits = [str2num(definput{1}) str2num(definput{2})];
                    app.GreenSizePixelsSlider.Limits = [str2num(definput{3}) str2num(definput{4})];
                    app.RedThresholdSlider.Limits = [str2num(definput{5}) str2num(definput{6})];
                    app.RedSizePixelsSlider.Limits = [str2num(definput{7}) str2num(definput{8})];
                    uiwait(msgbox('Error changing values. Please only enter valid numbers into prompt','Error'));
                    ChangeRangesButtonPushed(app, event);
                end
            end
            
        end

        % Button pushed function: SelectBoundingBoxButton
        function SelectBoundingBoxButtonPushed(app, event)
            if ~isempty(app.Blue_Image)
                app.ConfirmBoundingBoxButton.Enable = true;
                app.CurrentDisplayImg = 0;
                updateImageConfiguration(app);
                app.RectangleCrop = drawrectangle('Rotatable', true);
            else
                msgbox('Blue image must first be loaded before selecting BoundingBox','Error');
            end

        end

        % Button pushed function: ConfirmBoundingBoxButton
        function ConfirmBoundingBoxButtonPushed(app, event)
            app.ConfirmBoundingBoxButton.Enable = false;
            app.SelectCenterpointButton.Enable = true;
            app.MapExclusionButton.Enable = true;
            
            rectangleCropBoundingBoxMask = app.RectangleCrop.createMask();
            app.RectangleCropBoundingBoxOutline = boundarymask(rectangleCropBoundingBoxMask);
            rp = regionprops(rectangleCropBoundingBoxMask,'BoundingBox');
            app.RectangleCropBoundingBox = round(rp.BoundingBox);
            app.RectangleCropBoundingBoxConvexImage = imcrop(rectangleCropBoundingBoxMask, app.RectangleCropBoundingBox);
            app.RectangleCropBoundingBoxConvexImageOutline = boundarymask(app.RectangleCropBoundingBoxConvexImage);
            
            % Crop Images
            app.Blue_Image_Cropped = imcrop(app.Blue_Image, app.RectangleCropBoundingBox);
            if ~isempty(app.Green_Image)
                app.Green_Image_Cropped = imcrop(app.Green_Image, app.RectangleCropBoundingBox);
            end
            if ~isempty(app.Red_Image)
                app.Red_Image_Cropped = imcrop(app.Red_Image, app.RectangleCropBoundingBox);
            end
            
            app.RotateValue.Value = -1*app.RectangleCrop.RotationAngle;
            
            % Set up empty ROIs
            app.Selected_Blue_ROIs = false(size(app.Blue_Image_Cropped));
            app.Selected_Blue_ROI_Outlines = false(size(app.Blue_Image_Cropped));
            app.Selected_Green_ROIs = false(size(app.Blue_Image_Cropped));
            app.Selected_Green_ROI_Outlines = false(size(app.Blue_Image_Cropped));
            app.Selected_Red_ROIs = false(size(app.Blue_Image_Cropped));
            app.Selected_Red_ROI_Outlines = false(size(app.Blue_Image_Cropped));
            
            
            % Update display image to show new ROI
            close gcf;
            app.CurrentDisplayImg = 3;
            updateImageConfiguration(app);
        end

        % Button pushed function: SelectCenterpointButton
        function SelectCenterpointButtonPushed(app, event)
            app.CurrentDisplayImg = 3;
            updateImageConfiguration(app);
            app.CenterPoint = ginputYellow(1);
            updateImageConfiguration(app);
            if ~isempty(app.CenterPoint)
                % Ready to proceed, set process/checkImage buttons to green
                app.ConfirmImagesButton.BackgroundColor = [0 .91 0];
                app.ProcessBlueButton.BackgroundColor = [0 .91 0];
            end
        end

        % Button pushed function: ConfirmImagesButton
        function ConfirmImagesButtonPushed(app, event)
            % Check all images and move to processing tab
            
            if isempty(app.Blue_Image)
                msgbox('Blue image must first be loaded before proceeding','Error');
            elseif isempty(app.Blue_Image_Cropped)
                msgbox('Blue image must first be cropped before proceeding','Error');
            elseif isempty(app.CenterPoint)
                msgbox('Center point must first be set before proceeding','Error');
            else
                app.TabGroup.SelectedTab = app.RefChannelTab;
                app.ProcessButton.BackgroundColor = [0 .91 0]; % Change process button color to green
                updateBlueImage(app);
            end
            
        end

        % Button pushed function: SaveWorkspaceButton
        function SaveWorkspaceButtonPushed(app, event)
            [file,path] = uiputfile('*.mat');
            if ischar(file)
                ws.ProcessBlueButtonBackgroundColor = app.ProcessBlueButton.BackgroundColor;
                ws.ConfirmImagesButtonBackgroundColor = app.ConfirmImagesButton.BackgroundColor;
                ws.ProcessButtonBackgroundColor = app.ProcessButton.BackgroundColor;
                ws.ExportROIsButtonBackgroundColor = app.ExportROIsButton.BackgroundColor;
                ws.CommitBlueROIsButtonBackgroundColor = app.CommitBlueROIsButton.BackgroundColor;
                
                ws.BlueSizePixelsSliderLimits = app.MinBlueSizePixelsSlider.Limits;
                ws.GreenSizePixelsSliderLimits = app.GreenSizePixelsSlider.Limits;
                ws.RedSizePixelsSliderLimits = app.RedSizePixelsSlider.Limits;
                ws.BlueSizePixelsSliderValue = app.MinBlueSizePixelsSlider.Value;
                ws.GreenSizePixelsSliderValue = app.GreenSizePixelsSlider.Value;
                ws.RedSizePixelsSliderValue = app.RedSizePixelsSlider.Value;
                ws.MaxBlueSizePixelsSliderValue = app.MaxBlueSizePixelsSlider.Value;
                ws.MaxBlueSizePixelsSliderLimits = app.MaxBlueSizePixelsSlider.Limits;
                
                    
                ws.BlueThreshold2SliderLimits = app.BlueThresholdPass2Slider.Limits;
                ws.BlueThresholdSliderLimits = app.BlueThresholdPass1Slider.Limits;
                ws.GreenThresholdSliderLimits = app.GreenThresholdSlider.Limits;
                ws.RedThresholdSliderLimits = app.RedThresholdSlider.Limits;
                ws.BlueThreshold2SliderValue = app.BlueThresholdPass2Slider.Value;
                ws.BlueThresholdSliderValue = app.BlueThresholdPass1Slider.Value;
                ws.GreenThresholdSliderValue = app.GreenThresholdSlider.Value;
                ws.RedThresholdSliderValue = app.RedThresholdSlider.Value;
                ws.GreenThresholdSwitchValue = app.GreenThresholdSwitch.Value;
                ws.RedThresholdSwitchValue = app.RedThresholdSwitch.Value;
                ws.ShowChannelBlueSwitchValue = app.ShowChannelBlueSwitch.Value;
                ws.ShowChannelGreenSwitchValue = app.ShowChannelGreenSwitch.Value;
                ws.ShowChannelRedSwitchValue = app.ShowChannelRedSwitch.Value;
                ws.ShowROIBlueSwitchValue = app.ShowROIBlueSwitch.Value;
                ws.ShowROIGreenSwitchValue = app.ShowROIGreenSwitch.Value;
                ws.ShowROIRedSwitchValue = app.ShowROIRedSwitch.Value;
                
                ws.SelectBoundingBoxButtonEnable = app.SelectBoundingBoxButton.Enable;
                ws.ConfirmBoundingBoxButtonEnable = app.ConfirmBoundingBoxButton.Enable;
                ws.SelectCenterpointButtonEnable = app.SelectCenterpointButton.Enable;
                ws.MapExclusionButtonEnable = app.MapExclusionButton.Enable;
                
                ws.BlueOnlyLabelText = app.BlueOnlyLabel.Text;
                ws.BlueLabelText = app.BlueLabel.Text;
                ws.GreenLabelText = app.GreenLabel.Text;
                ws.RedLabelText = app.RedLabel.Text;
                
                ws.RectangleCrop = app.RectangleCrop;
                ws.RectangleCropBoundingBox = app.RectangleCropBoundingBox;
                ws.RectangleCropBoundingBoxOutline = app.RectangleCropBoundingBoxOutline;
                ws.RectangleCropBoundingBoxConvexImage = app.RectangleCropBoundingBoxConvexImage;
                ws.RectangleCropBoundingBoxConvexImageOutline = app.RectangleCropBoundingBoxConvexImageOutline;
                ws.CenterPoint = app.CenterPoint;
                
                % Blue image variables
                ws.Blue_Image = app.Blue_Image;
                ws.Blue_Image_Cropped = app.Blue_Image_Cropped;
                ws.Blue_Image_Filename = app.Blue_Image_Filename;
                ws.Blue_Image_Path = app.Blue_Image_Path;
                
                % Green image variables
                ws.Green_Image = app.Green_Image;
                ws.Green_Image_Cropped = app.Green_Image_Cropped;
                ws.Green_Image_Filename = app.Green_Image_Filename;
                ws.Green_Image_Path = app.Green_Image_Path;
                
                % Red image variables
                ws.Red_Image = app.Red_Image;
                ws.Red_Image_Cropped = app.Red_Image_Cropped;
                ws.Red_Image_Filename = app.Red_Image_Filename;
                ws.Red_Image_Path = app.Red_Image_Path;
                
                
                ws.Blue_ROI = app.Blue_ROI; % Container for the current ROI
                ws.Blue_ROI_Filename = app.Blue_ROI_Filename;        
                ws.Blue_ROI_Path = app.Blue_ROI_Path;
                ws.Selected_Blue_ROIs = app.Selected_Blue_ROIs; % Container for selected ROIs
                ws.Selected_Blue_ROI_Outlines = app.Selected_Blue_ROI_Outlines;
                ws.Blue_ROI_Outlines = app.Blue_ROI_Outlines;
                
                ws.Green_ROI = app.Green_ROI; % Container for the current ROI
                ws.Green_ROI_Filename = app.Green_ROI_Filename;        
                ws.Green_ROI_Path = app.Green_ROI_Path;
                ws.Selected_Green_ROIs = app.Selected_Green_ROIs; % Container for selected ROIs
                ws.Selected_Green_ROI_Outlines = app.Selected_Green_ROI_Outlines;
                
                ws.Red_ROI = app.Red_ROI; % Container for the current ROI
                ws.Red_ROI_Filename = app.Red_ROI_Filename;        
                ws.Red_ROI_Path = app.Red_ROI_Path;
                ws.Selected_Red_ROIs = app.Selected_Red_ROIs; % Container for selected ROIs
                ws.Selected_Red_ROI_Outlines = app.Selected_Red_ROI_Outlines;
                
                ws.DisplayImage = app.DisplayImage; % Current RGB display image 
                
                ws.CurrentDisplayImg = app.CurrentDisplayImg; % -1=Unset, 0=Blue, 1=Green, 2=Red, 3=Blue Cropped
                
                ws.GreenImageLabelText = app.GreenImageLabel.Text;
                ws.RedImageLabelText = app.RedImageLabel.Text;
                ws.BlueImageLabelText = app.BlueImageLabel.Text;
                
                ws.RotateValueValue = app.RotateValue.Value;
            
                save([path file], 'ws', '-v7.3');
                
                msgbox(['Workspace successfully saved as ' file],'Save Successful!');
            end
            
        end

        % Button pushed function: LoadWorkspaceButton
        function LoadWorkspaceButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if ischar(file)
                if exist([path file])
                    
                    load([path file]);
                    
                    app.ProcessBlueButton.BackgroundColor = ws.ProcessBlueButtonBackgroundColor;
                    app.ConfirmImagesButton.BackgroundColor = ws.ConfirmImagesButtonBackgroundColor;
                    app.ProcessButton.BackgroundColor = ws.ProcessButtonBackgroundColor;
                    app.ExportROIsButton.BackgroundColor = ws.ExportROIsButtonBackgroundColor;
                    app.CommitBlueROIsButton.BackgroundColor = ws.CommitBlueROIsButtonBackgroundColor;
                    
                    app.MinBlueSizePixelsSlider.Limits = ws.BlueSizePixelsSliderLimits;
                    app.GreenSizePixelsSlider.Limits = ws.GreenSizePixelsSliderLimits;
                    app.RedSizePixelsSlider.Limits = ws.RedSizePixelsSliderLimits;
                    app.MinBlueSizePixelsSlider.Value = ws.BlueSizePixelsSliderValue;
                    app.GreenSizePixelsSlider.Value = ws.GreenSizePixelsSliderValue;
                    app.RedSizePixelsSlider.Value = ws.RedSizePixelsSliderValue;
                    app.MaxBlueSizePixelsSlider.Limits = ws.MaxBlueSizePixelsSliderLimits;
                    app.MaxBlueSizePixelsSlider.Value = ws.MaxBlueSizePixelsSliderValue;
                    
                    app.BlueThresholdPass2Slider.Limits = ws.BlueThreshold2SliderLimits;
                    app.BlueThresholdPass1Slider.Limits = ws.BlueThresholdSliderLimits;
                    app.GreenThresholdSlider.Limits = ws.GreenThresholdSliderLimits;
                    app.RedThresholdSlider.Limits = ws.RedThresholdSliderLimits;
                    app.BlueThresholdPass2Slider.Value = ws.BlueThreshold2SliderValue;
                    app.BlueThresholdPass1Slider.Value = ws.BlueThresholdSliderValue;
                    app.GreenThresholdSlider.Value = ws.GreenThresholdSliderValue;
                    app.RedThresholdSlider.Value = ws.RedThresholdSliderValue;
                    app.GreenThresholdSwitch.Value = ws.GreenThresholdSwitchValue;
                    app.RedThresholdSwitch.Value = ws.RedThresholdSwitchValue;
                    app.ShowChannelBlueSwitch.Value = ws.ShowChannelBlueSwitchValue;
                    app.ShowChannelGreenSwitch.Value = ws.ShowChannelGreenSwitchValue;
                    app.ShowChannelRedSwitch.Value = ws.ShowChannelRedSwitchValue;
                    app.ShowROIBlueSwitch.Value = ws.ShowROIBlueSwitchValue;
                    app.ShowROIGreenSwitch.Value = ws.ShowROIGreenSwitchValue;
                    app.ShowROIRedSwitch.Value = ws.ShowROIRedSwitchValue;
                    
                    app.SelectBoundingBoxButton.Enable = ws.SelectBoundingBoxButtonEnable;
                    app.ConfirmBoundingBoxButton.Enable = ws.ConfirmBoundingBoxButtonEnable;
                    app.SelectCenterpointButton.Enable = ws.SelectCenterpointButtonEnable;
                    app.MapExclusionButton.Enable = ws.MapExclusionButtonEnable;
                    
                    app.BlueOnlyLabel.Text = ws.BlueOnlyLabelText;
                    app.BlueLabel.Text = ws.BlueLabelText;
                    app.GreenLabel.Text = ws.GreenLabelText;
                    app.RedLabel.Text = ws.RedLabelText;
                    
                    app.RectangleCrop = ws.RectangleCrop;
                    app.RectangleCropBoundingBox = ws.RectangleCropBoundingBox;
                    app.RectangleCropBoundingBoxOutline = ws.RectangleCropBoundingBoxOutline;
                    app.RectangleCropBoundingBoxConvexImage = ws.RectangleCropBoundingBoxConvexImage;
                    app.RectangleCropBoundingBoxConvexImageOutline = ws.RectangleCropBoundingBoxConvexImageOutline;
                    app.CenterPoint = ws.CenterPoint;
                    
                    % Blue image variables
                    app.Blue_Image = ws.Blue_Image;
                    app.Blue_Image_Cropped = ws.Blue_Image_Cropped;
                    app.Blue_Image_Filename = ws.Blue_Image_Filename;
                    app.Blue_Image_Path = ws.Blue_Image_Path;
                    
                    % Green image variables
                    app.Green_Image = ws.Green_Image;
                    app.Green_Image_Cropped = ws.Green_Image_Cropped;
                    app.Green_Image_Filename = ws.Green_Image_Filename;
                    app.Green_Image_Path = ws.Green_Image_Path;
                    
                    % Red image variables
                    app.Red_Image = ws.Red_Image;
                    app.Red_Image_Cropped = ws.Red_Image_Cropped;
                    app.Red_Image_Filename = ws.Red_Image_Filename;
                    app.Red_Image_Path = ws.Red_Image_Path;
                    
                    
                    app.Blue_ROI = ws.Blue_ROI; % Container for the current ROI
                    app.Blue_ROI_Filename = ws.Blue_ROI_Filename;        
                    app.Blue_ROI_Path = ws.Blue_ROI_Path;
                    app.Selected_Blue_ROIs = ws.Selected_Blue_ROIs; % Container for selected ROIs
                    app.Selected_Blue_ROI_Outlines = ws.Selected_Blue_ROI_Outlines;
                    app.Blue_ROI_Outlines = ws.Blue_ROI_Outlines;
                    
                    app.Green_ROI = ws.Green_ROI; % Container for the current ROI
                    app.Green_ROI_Filename = ws.Green_ROI_Filename;        
                    app.Green_ROI_Path = ws.Green_ROI_Path;
                    app.Selected_Green_ROIs = ws.Selected_Green_ROIs; % Container for selected ROIs
                    app.Selected_Green_ROI_Outlines = ws.Selected_Green_ROI_Outlines;
                    
                    app.Red_ROI = ws.Red_ROI; % Container for the current ROI
                    app.Red_ROI_Filename = ws.Red_ROI_Filename;        
                    app.Red_ROI_Path = ws.Red_ROI_Path;
                    app.Selected_Red_ROIs = ws.Selected_Red_ROIs; % Container for selected ROIs
                    app.Selected_Red_ROI_Outlines = ws.Selected_Red_ROI_Outlines;
                    
                    app.DisplayImage = ws.DisplayImage; % Current RGB display image 
                    
                    app.CurrentDisplayImg = ws.CurrentDisplayImg; % -1=Unset, 0=Blue, 1=Green, 2=Red, 3=Blue Cropped
                    
                    app.GreenImageLabel.Text = ws.GreenImageLabelText;
                    app.RedImageLabel.Text = ws.RedImageLabelText;
                    app.BlueImageLabel.Text = ws.BlueImageLabelText;
                    
                    app.RotateValue.Value = ws.RotateValueValue;   
                    
                    % Try to detect where previously left off by data
                    % present, autoselect tab and show image
                    if sum(sum(app.Selected_Red_ROIs)) > 0 || sum(sum(app.Selected_Green_ROIs)) > 0
                        app.TabGroup.SelectedTab = app.AdditionalChannelsTab;
                        updateImage(app);
                    elseif sum(sum(app.Blue_ROI)) > 0
                        app.TabGroup.SelectedTab = app.RefChannelTab;
                        updateBlueImage(app);
                    else
                        updateImageConfiguration(app);
                    end
                    
                    msgbox('Workspace successfully loaded!','Load Successful!');
                else
                    msgbox(['File ' file 'does not exist!'],'Error');
                end
            end
        end

        % Button pushed function: ExportROIsButton
        function ExportROIsButtonPushed(app, event)
            
            if isempty(app.Selected_Blue_ROIs)
                msgbox('Must first process blue channel before export.','Error');
                return;
            end
            
            [file,path] = uiputfile('*.csv');
            
            if ischar(file) 
                rp = regionprops(app.Selected_Blue_ROIs, 'Centroid');
                centroids = cat(1,rp.Centroid);
                colors = [ones(size(centroids,1), 1) zeros(size(centroids,1), 2)];
                
                total_blue = size(centroids,1);
                total_green = 0;
                total_red = 0;
                
                if ~isempty(app.Selected_Green_ROIs)
                    rp = regionprops(app.Selected_Green_ROIs, 'Centroid');
                    centroids_green = cat(1,rp.Centroid);
                    colors_green = [ones(size(centroids_green,1), 2) zeros(size(centroids_green,1), 1)];
                    centroids = [centroids; centroids_green];
                    colors = [colors; colors_green];
                    total_green = size(centroids_green,1);
                end
                
                if ~isempty(app.Selected_Red_ROIs)
                    rp = regionprops(app.Selected_Red_ROIs, 'Centroid');
                    centroids_red = cat(1,rp.Centroid);
                    colors_red = [ones(size(centroids_red,1), 1) zeros(size(centroids_red,1), 1) ones(size(centroids_red,1), 1)];
                    centroids = [centroids; centroids_red];
                    colors = [colors; colors_red];
                    total_red = size(centroids_red,1);
                end
                header = [0  app.RectangleCropBoundingBox(3)  app.RectangleCropBoundingBox(4) total_blue total_green total_red];
                indices = [1:size(centroids, 1)]';
                centroids(:,1) = centroids(:,1) - app.CenterPoint(1);
                centroids(:,2) = centroids(:,2) - app.CenterPoint(2);
                
                writematrix([header; [indices centroids colors]],[path file]);
                
                msgbox(['CSV file ' file ' successfully written!'],'Success!');
            end
%             SaveWorkspaceButtonPushed(app, event);
        end

        % Button pushed function: ProcessBlueButton
        function ProcessBlueButtonPushed(app, event)
            if ~isempty(app.Blue_Image_Cropped) % Only pocess if blue image is loaded
                app.ProcessBlueButton.BackgroundColor = [.91 0 0]; % Change process blue button color to red
                
                %% Pass 1
                procBlueImage = app.Blue_Image_Cropped;
                procBlueImage = medfilt2(procBlueImage, [2 2]); % median filt to remove noise
                blue_bw = bwareaopen(imclose(procBlueImage > app.BlueThresholdPass1Slider.Value, strel('disk', 1)), round(app.MinBlueSizePixelsSlider.Value)); % threshold and area filter
                blue_bw = and(blue_bw, ~bwareaopen(blue_bw, round(app.MaxBlueSizePixelsSlider.Value)));
                blue_bw(~app.RectangleCropBoundingBoxConvexImage) = 0;
                blue_bw = imfill(blue_bw,'holes'); % fill holes in bw
                
                
                % Set up Blue ROI variables
                app.Blue_ROI = blue_bw;
                app.Selected_Blue_ROIs = bwlabel(blue_bw);
                app.Selected_Blue_ROI_Outlines = boundarymask(blue_bw);
                
                %% Pass 2
                blue_bw = bwareaopen(imclose(and(~imdilate(blue_bw, strel('disk', 10)), procBlueImage > app.BlueThresholdPass2Slider.Value), strel('disk', 1)), round(app.MinBlueSizePixelsSlider.Value)); % threshold and area filter
                blue_bw = and(blue_bw, ~bwareaopen(blue_bw, round(app.MaxBlueSizePixelsSlider.Value)));
                blue_bw(~app.RectangleCropBoundingBoxConvexImage) = 0;
                blue_bw = imfill(blue_bw,'holes'); % fill holes in bw
                
                % Add to Blue ROI variables
                app.Blue_ROI = app.Blue_ROI | blue_bw;
                blue_lbl = bwlabel(blue_bw);
                blue_lbl(blue_lbl>0) = blue_lbl(blue_lbl>0) + max(max(app.Selected_Blue_ROIs));
                app.Selected_Blue_ROIs = app.Selected_Blue_ROIs + blue_lbl;
                app.Selected_Blue_ROI_Outlines = app.Selected_Blue_ROI_Outlines | boundarymask(blue_bw);
                
                app.BlueOnlyLabel.Text = num2str(max(max(app.Selected_Blue_ROIs)));
                
                updateBlueImage(app);
                
                app.ProcessBlueButton.BackgroundColor = [0 .91 0]; % Change process blue button color back to green
                app.CommitBlueROIsButton.BackgroundColor = [0 .91 0]; % Change commit button color to green
            else
                msgbox('Blue image must first be loaded before processing!','Error');
            end
            
        end

        % Button pushed function: CommitBlueROIsButton
        function CommitBlueROIsButtonPushed(app, event)
            if ~isempty(app.Selected_Blue_ROIs)             
                app.Blue_ROI = app.Selected_Blue_ROIs;
                app.Blue_ROI_Outlines = app.Selected_Blue_ROI_Outlines;
                app.ProcessButton.BackgroundColor = [0 .91 0]; % Change process button color to green
                app.TabGroup.SelectedTab = app.AdditionalChannelsTab;
                updateImage(app);
            else
                msgbox('Blue image must first be processed before continuing!','Error');
            end
        end

        % Button pushed function: RemoveROIsButton
        function RemoveROIsButtonPushed(app, event)
            if isempty(app.Selected_Blue_ROIs)
                msgbox('Images must first be processed before editing ROIs','Error');
                return;
            end
            
            updateImage(app);
            
            % Change figure title to remove instructions
            app.ImageFigure.Name = 'Remove Mode - Left click to remove ROIs. Right click when done.';
            
            % Loop through user inputs and remove selected ROIs accordingly
            UserSelection = [0 0];
            while size(UserSelection, 1) > 0
                [UserSelection(1), UserSelection(2), button] = ginputYellow(1);
                
                if button == 3
                    break;
                end
                
                if size(UserSelection, 1) > 0
                    UserSelection = round(translateCoord(app, [UserSelection(2); UserSelection(1)]));
                    
                    if app.Selected_Blue_ROIs(UserSelection(1), UserSelection(2)) > 0
                        % Remove ROI Masks
                       UserSelectionIdx = app.Selected_Blue_ROIs(UserSelection(1), UserSelection(2));
                       temp_blue = app.Selected_Blue_ROIs == UserSelectionIdx;
                       app.Selected_Blue_ROIs(temp_blue) = 0;
                       app.Selected_Blue_ROI_Outlines = xor(app.Selected_Blue_ROI_Outlines, boundarymask(temp_blue));     
                    elseif  app.Selected_Red_ROIs(UserSelection(1), UserSelection(2)) > 0
                        UserSelectionIdx = app.Selected_Red_ROIs(UserSelection(1), UserSelection(2));
                        app.Selected_Red_ROIs(app.Selected_Red_ROIs == UserSelectionIdx) = 0;
                        app.Selected_Red_ROI_Outlines = boundarymask(app.Selected_Red_ROIs>0);
                    elseif  app.Selected_Green_ROIs(UserSelection(1), UserSelection(2)) > 0    
                        UserSelectionIdx = app.Selected_Green_ROIs(UserSelection(1), UserSelection(2));
                        app.Selected_Green_ROIs(app.Selected_Green_ROIs == UserSelectionIdx) = 0;
                        app.Selected_Green_ROI_Outlines = boundarymask(app.Selected_Green_ROIs>0);
                    end    
                        
                        updateImage(app);
                    
                end
            end
            
            updateIndices(app);
            
            % Reset figure name and update image
            app.ImageFigure.Name = 'Image Display';
            updateImage(app);
        end

        % Button pushed function: ChangeBlueRangesButton
        function ChangeBlueRangesButtonPushed(app, event)
            prompt = {'Min Blue Threshold 1:','Max Blue Threshold 1:', 'Min Blue Threshold 2:','Max Blue Threshold 2:', 'Min Area Threshold (Lower Limit):','Min Area Threshold (Upper Limit):', 'Max Area Threshold (Lower Limit):','Max Area Threshold (Upper Limit):'};
            dlgtitle = 'Enter New Threshold Range Values';
            dims = [1 60];
            definput = {num2str(app.BlueThresholdPass1Slider.Limits(1)),num2str(app.BlueThresholdPass1Slider.Limits(2)),num2str(app.BlueThresholdPass2Slider.Limits(1)),num2str(app.BlueThresholdPass2Slider.Limits(2)), num2str(app.MinBlueSizePixelsSlider.Limits(1)),num2str(app.MinBlueSizePixelsSlider.Limits(2)),num2str(app.MaxBlueSizePixelsSlider.Limits(1)),num2str(app.MaxBlueSizePixelsSlider.Limits(2))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if ~isempty(answer)
                try
                    % Try to change limits
                    app.BlueThresholdPass1Slider.Limits = [str2num(answer{1}) str2num(answer{2})];
                    app.BlueThresholdPass2Slider.Limits = [str2num(answer{3}) str2num(answer{4})];
                    app.MinBlueSizePixelsSlider.Limits = [str2num(answer{5}) str2num(answer{6})];
                    app.MaxBlueSizePixelsSlider.Limits = [str2num(answer{7}) str2num(answer{8})];
                catch
                    % Reset limits and show error message
                    app.BlueThresholdPass1Slider.Limits = [str2num(definput{1}) str2num(definput{2})];
                    app.BlueThresholdPass2Slider.Limits = [str2num(definput{3}) str2num(definput{4})];
                    app.MinBlueSizePixelsSlider.Limits = [str2num(definput{5}) str2num(definput{6})];
                    app.MaxBlueSizePixelsSlider.Limits = [str2num(definput{7}) str2num(definput{8})];
                    uiwait(msgbox('Error changing values. Please only enter valid numbers into prompt','Error'));
                    ChangeRangesButtonPushed(app, event);
                end
            end
        end

        % Button pushed function: AddROIsButton
        function AddROIsButtonPushed(app, event)
            if isempty(app.Selected_Blue_ROIs)
                msgbox('Images must first be processed before editing ROIs','Error');
                return;
            end
            
            updateBlueImage(app);
            
            % Change figure title to remove instructions
            app.ImageFigure.Name = 'Add Mode - Click to add ROIs. Right click when done.';
            
            props = regionprops(app.Blue_ROI, 'BoundingBox');
            avg_bb = cat(1,props.BoundingBox);
            avg_bb = ceil(mean2(avg_bb(:,3:4)));
            
            
            % Loop through user inputs and remove selected ROIs accordingly
            UserSelection = round(app.CenterPoint);
            while size(UserSelection, 1) > 0 && app.RectangleCropBoundingBoxConvexImage(UserSelection(1), UserSelection(2))
                [UserSelection(1), UserSelection(2), button] = ginputYellow(1);
                
                if button == 3
                    break;
                end
                
                if size(UserSelection, 1) > 0
                    UserSelection = round(translateCoord(app, [UserSelection(2); UserSelection(1)]));
                    try
                        threshold = app.Blue_Image_Cropped(UserSelection(1), UserSelection(2)) - 40;
                    catch
                        break;
                    end
                    
                    if threshold > 5
                        imageRegion = app.Blue_Image_Cropped(UserSelection(1)-avg_bb:UserSelection(1)+avg_bb, UserSelection(2)-avg_bb:UserSelection(2)+avg_bb);
                        imageRegion = medfilt2(imageRegion, [2 2]); % median filt to remove noise
                        imageRegion_bw = imageRegion >= threshold;
                        imageRegion_bw = and(imageRegion_bw, ~imdilate(app.Selected_Blue_ROIs(UserSelection(1)-avg_bb:UserSelection(1)+avg_bb, UserSelection(2)-avg_bb:UserSelection(2)+avg_bb),strel('disk', 2)));
                        imageRegion_bw = bwareaopen(imageRegion_bw, round(app.MinBlueSizePixelsSlider.Value));
                        imageRegion_bw = bwareafilt(imfill(imageRegion_bw,'holes'),1);
                        
                        app.Selected_Blue_ROIs(UserSelection(1)-avg_bb:UserSelection(1)+avg_bb, UserSelection(2)-avg_bb:UserSelection(2)+avg_bb) = app.Selected_Blue_ROIs(UserSelection(1)-avg_bb:UserSelection(1)+avg_bb, UserSelection(2)-avg_bb:UserSelection(2)+avg_bb) +  (max(max(app.Selected_Blue_ROIs))+1)*imageRegion_bw;
                        app.Selected_Blue_ROI_Outlines(UserSelection(1)-avg_bb:UserSelection(1)+avg_bb, UserSelection(2)-avg_bb:UserSelection(2)+avg_bb) = or(app.Selected_Blue_ROI_Outlines(UserSelection(1)-avg_bb:UserSelection(1)+avg_bb, UserSelection(2)-avg_bb:UserSelection(2)+avg_bb), boundarymask(imageRegion_bw>0));
    
                        updateBlueImage(app);
                    end
                end
            end
            
            updateIndices(app);
            
            % Reset figure name and update image
            app.ImageFigure.Name = 'Image Display';
            updateBlueImage(app);
        end

        % Button pushed function: UpdateBlueDisplayButton
        function UpdateBlueDisplayButtonPushed(app, event)
            updateBlueImage(app);
        end

        % Button pushed function: SplitROIsButton
        function SplitROIsButtonPushed(app, event)
            if isempty(app.Selected_Blue_ROIs)
                msgbox('Images must first be processed before editing ROIs','Error');
                return;
            end
            
            % Loop through user inputs and remove selected ROIs accordingly
            UserSelections = unique(app.Selected_Blue_ROIs);
            UserSelections(UserSelections==0) = [];
            tic;
            for c = 1:numel(UserSelections)
                imageRegion_idx = UserSelections(c);
                        props = regionprops(app.Selected_Blue_ROIs==imageRegion_idx, 'BoundingBox');
                        imageRegion_mask = imcrop(app.Selected_Blue_ROIs==imageRegion_idx, props(1).BoundingBox);
                        imageRegion = imcrop(app.Blue_Image_Cropped, props(1).BoundingBox);
                        imageRegion(imageRegion_mask == 0) = 0;
                        imageRegion = medfilt2(imageRegion, [2 2]); % median filt to remove noise
                        
                        for threshold = round(app.BlueThresholdPass1Slider.Value+10):5:max(max(imageRegion))
                            imageRegion_bw = imageRegion >= threshold;
                            imageRegion_bw = imfill(imageRegion_bw,'holes');
                            imageRegion_bw = imerode(imageRegion_bw,strel('disk', 2));
                            imageRegion_bw = bwareaopen(imageRegion_bw, round(app.MinBlueSizePixelsSlider.Value));
                            
                            imageRegion_lbl = bwlabel(imageRegion_bw);
                            
                            if max(max(imageRegion_lbl)) > 1
                                % remove old
                                app.Selected_Blue_ROI_Outlines = xor(app.Selected_Blue_ROI_Outlines, boundarymask(app.Selected_Blue_ROIs==imageRegion_idx));
                                app.Selected_Blue_ROIs(app.Selected_Blue_ROIs==imageRegion_idx) = 0;
                                
                                % add new
                                imageRegion_lbl(imageRegion_lbl>1) = imageRegion_lbl(imageRegion_lbl>1)+1+max(max(app.Selected_Blue_ROIs));
                                imageRegion_lbl(imageRegion_lbl==1) = imageRegion_idx;
                                
                                app.Selected_Blue_ROIs(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))) = app.Selected_Blue_ROIs(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))) + imageRegion_lbl;
                                app.Selected_Blue_ROI_Outlines(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))) = or(app.Selected_Blue_ROI_Outlines(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))), boundarymask(imageRegion_lbl>0));
                                
                                break;
                            end
                            
                        end
            end
            toc
%%%%%%%%%%%%%% Old Version that is click based %%%%%%%%%%%%%%%%%%%%%%%%%%%            
%             updateBlueImage(app);
%             
%             % Change figure title to remove instructions
%             app.ImageFigure.Name = 'Split Mode - Click to split ROIs. Right click when done.';
%             
%             % Loop through user inputs and remove selected ROIs accordingly
%             UserSelection = round(app.CenterPoint);
%             while size(UserSelection, 1) > 0 && app.RectangleCropBoundingBoxConvexImage(UserSelection(1), UserSelection(2))
%                 [UserSelection(1), UserSelection(2), button] = ginputYellow(1);
%                 
%                 if button == 3
%                     break;
%                 end
%                 
%                 if size(UserSelection, 1) > 0
%                     UserSelection = round(translateCoord(app, [UserSelection(2); UserSelection(1)]));
%                     
%                     % Extract Region around ROI to operate on
%                     imageRegion_idx = app.Selected_Blue_ROIs(UserSelection(1), UserSelection(2));
%                     if imageRegion_idx > 0
%                         props = regionprops(app.Selected_Blue_ROIs==imageRegion_idx, 'BoundingBox');
%                         imageRegion_mask = imcrop(app.Selected_Blue_ROIs==imageRegion_idx, props(1).BoundingBox);
%                         imageRegion = imcrop(app.Blue_Image_Cropped, props(1).BoundingBox);
%                         imageRegion(imageRegion_mask == 0) = 0;
%                         imageRegion = medfilt2(imageRegion, [2 2]); % median filt to remove noise
%                         
%                         for threshold = round(app.BlueThresholdPass1Slider.Value+10):5:max(max(imageRegion))
%                             imageRegion_bw = imageRegion >= threshold;
%                             imageRegion_bw = imfill(imageRegion_bw,'holes');
%                             imageRegion_bw = imerode(imageRegion_bw,strel('disk', 2));
%                             imageRegion_bw = bwareaopen(imageRegion_bw, round(app.MinBlueSizePixelsSlider.Value));
%                             
%                             imageRegion_lbl = bwlabel(imageRegion_bw);
%                             
%                             if max(max(imageRegion_lbl)) > 1
%                                 % remove old
%                                 app.Selected_Blue_ROI_Outlines = xor(app.Selected_Blue_ROI_Outlines, boundarymask(app.Selected_Blue_ROIs==imageRegion_idx));
%                                 app.Selected_Blue_ROIs(app.Selected_Blue_ROIs==imageRegion_idx) = 0;
%                                 
%                                 % add new
%                                 imageRegion_lbl(imageRegion_lbl>1) = imageRegion_lbl(imageRegion_lbl>1)+1+max(max(app.Selected_Blue_ROIs));
%                                 imageRegion_lbl(imageRegion_lbl==1) = imageRegion_idx;
%                                 
%                                 app.Selected_Blue_ROIs(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))) = app.Selected_Blue_ROIs(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))) + imageRegion_lbl;
%                                 app.Selected_Blue_ROI_Outlines(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))) = or(app.Selected_Blue_ROI_Outlines(props(1).BoundingBox(2):(props(1).BoundingBox(2) + props(1).BoundingBox(4)), props(1).BoundingBox(1):(props(1).BoundingBox(1) + props(1).BoundingBox(3))), boundarymask(imageRegion_lbl>0));
%                                 
%                                 break;
%                             end
%                             
%                         end
%                         updateBlueImage(app);
%                     end
%                 end
%             end
            
            updateIndices(app);
            
            % Reset figure name and update image
            app.ImageFigure.Name = 'Image Display';
            updateBlueImage(app);
        end

        % Value changed function: ColorizeBlueSwitch
        function ColorizeBlueSwitchValueChanged(app, event)
            updateBlueImage(app);
        end

        % Value changed function: ColorizeAllSwitch
        function ColorizeAllSwitchValueChanged(app, event)
            updateImage(app);
        end

        % Button pushed function: AddArbitraryROIsButton
        function AddArbitraryROIsButtonPushed(app, event)
            if isempty(app.Selected_Blue_ROIs)
                msgbox('Images must first be processed before editing ROIs','Error');
                return;
            end
            
            updateBlueImage(app);
            
            % Change figure title to remove instructions
            app.ImageFigure.Name = 'Add Arb Mode - Click to add arbitrary ROIs. Right click when done.';
            
            props = regionprops(app.Blue_ROI, 'EquivDiameter');
            avg_r = round(mean(cat(1,props.EquivDiameter))/2);
            
            arb_roi = padarray(strel('disk', avg_r, 0).Neighborhood, [1 1], 0);
            arb_bound = boundarymask(arb_roi);
            arb_r = avg_r+1;
            
            % Loop through user inputs and remove selected ROIs accordingly
            while true
                UserSelections = [];
                button = 1;
                while button == 1
                    [UserSelection(1), UserSelection(2), button] = ginputYellow(1);
                    UserSelections(1,end+1) = UserSelection(1);
                    UserSelections(2,end) = UserSelection(2);
                end
                
                for c = 1:size(UserSelections, 2)-1
                    UserSelection_c = round(translateCoord(app, [UserSelections(2,c); UserSelections(1,c)]));
                    app.Selected_Blue_ROIs(UserSelection_c(1)-arb_r:UserSelection_c(1)+arb_r, UserSelection_c(2)-arb_r:UserSelection_c(2)+arb_r) = (max(max(app.Selected_Blue_ROIs))+1)*arb_roi;
                    app.Selected_Blue_ROI_Outlines(UserSelection_c(1)-arb_r:UserSelection_c(1)+arb_r, UserSelection_c(2)-arb_r:UserSelection_c(2)+arb_r) = or(app.Selected_Blue_ROI_Outlines(UserSelection_c(1)-arb_r:UserSelection_c(1)+arb_r, UserSelection_c(2)-arb_r:UserSelection_c(2)+arb_r), arb_bound);
                end
                
                if size(UserSelections, 2) == 1
                    break;
                end 
                updateBlueImage(app);
            end
            
            updateIndices(app);
            
            % Reset figure name and update image
            app.ImageFigure.Name = 'Image Display';
            updateBlueImage(app);
        end

        % Button pushed function: MapExclusionButton
        function MapExclusionButtonPushed(app, event)
            if ~isempty(app.RectangleCropBoundingBoxConvexImage)
                updateImageConfiguration(app);
                p = drawpolygon('LineWidth',7,'Color','cyan');
                exclusion_zone = poly2mask(p.Position(:,1), p.Position(:,2), size(app.Blue_Image_Cropped, 1), size(app.Blue_Image_Cropped, 2));
                app.RectangleCropBoundingBoxConvexImage(exclusion_zone) = 0;                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellCountingGUIUIFigure = uifigure('Visible', 'off');
            app.CellCountingGUIUIFigure.Name = 'Cell Counting GUI';
            app.TabGroup = uitabgroup(app.CellCountingGUIUIFigure);
            app.LoadImagesTab = uitab(app.TabGroup);
            app.LoadImagesTab.Title = 'Load Images';
            app.ReferenceChannelPanel = uipanel(app.LoadImagesTab);
            app.ReferenceChannelPanel.Title = 'Reference Channel';
            app.LoadBlueButton = uibutton(app.ReferenceChannelPanel, 'push');
            app.LoadBlueButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBlueButtonPushed, true);
            app.LoadBlueButton.Text = 'Load Blue';
            app.BlueImageLabel = uilabel(app.ReferenceChannelPanel);
            app.BlueImageLabel.Text = 'Blue Image';
            app.SelectBoundingBoxButton = uibutton(app.ReferenceChannelPanel, 'push');
            app.SelectBoundingBoxButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBoundingBoxButtonPushed, true);
            app.SelectBoundingBoxButton.Enable = 'off';
            app.SelectBoundingBoxButton.Text = 'Select BoundingBox';
            app.ConfirmBoundingBoxButton = uibutton(app.ReferenceChannelPanel, 'push');
            app.ConfirmBoundingBoxButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmBoundingBoxButtonPushed, true);
            app.ConfirmBoundingBoxButton.Enable = 'off';
            app.ConfirmBoundingBoxButton.Text = 'Confirm BoundingBox';
            app.SelectCenterpointButton = uibutton(app.ReferenceChannelPanel, 'push');
            app.SelectCenterpointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCenterpointButtonPushed, true);
            app.SelectCenterpointButton.Enable = 'off';
            app.SelectCenterpointButton.Text = 'Select Centerpoint';
            app.MapExclusionButton = uibutton(app.ReferenceChannelPanel, 'push');
            app.MapExclusionButton.ButtonPushedFcn = createCallbackFcn(app, @MapExclusionButtonPushed, true);
            app.MapExclusionButton.Enable = 'off';
            app.MapExclusionButton.Text = 'Map Exclusion';
            app.AdditionalChannelsPanel = uipanel(app.LoadImagesTab);
            app.AdditionalChannelsPanel.Title = 'Additional Channels';
            app.LoadGreenButton = uibutton(app.AdditionalChannelsPanel, 'push');
            app.LoadGreenButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGreenButtonPushed, true);
            app.LoadGreenButton.Text = 'Load Green';
            app.GreenImageLabel = uilabel(app.AdditionalChannelsPanel);
            app.GreenImageLabel.Text = 'Green Image';
            app.LoadRedButton = uibutton(app.AdditionalChannelsPanel, 'push');
            app.LoadRedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRedButtonPushed, true);
            app.LoadRedButton.Text = 'Load Red';
            app.RedImageLabel = uilabel(app.AdditionalChannelsPanel);
            app.RedImageLabel.Text = 'Red Image';
            app.ClearAllButton = uibutton(app.LoadImagesTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.SaveWorkspaceButton = uibutton(app.LoadImagesTab, 'push');
            app.SaveWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWorkspaceButtonPushed, true);
            app.SaveWorkspaceButton.Text = 'Save Workspace';
            app.LoadWorkspaceButton = uibutton(app.LoadImagesTab, 'push');
            app.LoadWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkspaceButtonPushed, true);
            app.LoadWorkspaceButton.Text = 'Load Workspace';
            app.ConfirmImagesButton = uibutton(app.LoadImagesTab, 'push');
            app.ConfirmImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmImagesButtonPushed, true);
            app.ConfirmImagesButton.Text = 'Confirm Images';
            app.ExitButton = uibutton(app.LoadImagesTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.LoadImagesTab);
            app.Image.ImageSource = 'NIH-2012-logo.png';
            app.RefChannelTab = uitab(app.TabGroup);
            app.RefChannelTab.Title = 'Ref Channel';
            app.BlueThresholdPass1Label = uilabel(app.RefChannelTab);
            app.BlueThresholdPass1Label.Text = 'BlueThreshold Pass 1';
            app.BlueThresholdPass1Slider = uislider(app.RefChannelTab);
            app.BlueThresholdPass1Slider.Limits = [100 200];
            app.BlueThresholdPass1Slider.Value = 150;
            app.MinBlueSizePixelsSliderLabel = uilabel(app.RefChannelTab);
            app.MinBlueSizePixelsSliderLabel.Text = 'Min Blue Size (Pixels)';
            app.MinBlueSizePixelsSlider = uislider(app.RefChannelTab);
            app.MinBlueSizePixelsSlider.Value = 75;
            app.AddROIsButton = uibutton(app.RefChannelTab, 'push');
            app.AddROIsButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIsButtonPushed, true);
            app.AddROIsButton.Text = 'Add ROIs';
            app.RemoveBlueROIsButton = uibutton(app.RefChannelTab, 'push');
            app.RemoveBlueROIsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBlueROIsButtonPushed, true);
            app.RemoveBlueROIsButton.Text = 'Remove Blue ROIs';
            app.BlueOnlyLabel = uilabel(app.RefChannelTab);
            app.BlueOnlyLabel.Text = 'Blue';
            app.PopulationLabel = uilabel(app.RefChannelTab);
            app.PopulationLabel.Text = 'Population';
            app.RotationPanel = uipanel(app.RefChannelTab);
            app.RotationPanel.Title = 'Rotation';
            app.DegreeLabel = uilabel(app.RotationPanel);
            app.DegreeLabel.Text = 'Degree';
            app.RotateValue = uieditfield(app.RotationPanel, 'numeric');
            app.ProcessBlueButton = uibutton(app.RefChannelTab, 'push');
            app.ProcessBlueButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessBlueButtonPushed, true);
            app.ProcessBlueButton.Text = 'Process Blue!';
            app.CommitBlueROIsButton = uibutton(app.RefChannelTab, 'push');
            app.CommitBlueROIsButton.ButtonPushedFcn = createCallbackFcn(app, @CommitBlueROIsButtonPushed, true);
            app.CommitBlueROIsButton.Text = 'Commit Blue ROIs';
            app.ChangeBlueRangesButton = uibutton(app.RefChannelTab, 'push');
            app.ChangeBlueRangesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeBlueRangesButtonPushed, true);
            app.ChangeBlueRangesButton.Text = 'Change Blue Ranges';
            app.UpdateBlueDisplayButton = uibutton(app.RefChannelTab, 'push');
            app.UpdateBlueDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateBlueDisplayButtonPushed, true);
            app.UpdateBlueDisplayButton.Text = 'Update Display';
            app.SplitROIsButton = uibutton(app.RefChannelTab, 'push');
            app.SplitROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SplitROIsButtonPushed, true);
            app.SplitROIsButton.Text = 'Split ROIs';
            app.ColorizeSwitchLabel = uilabel(app.RefChannelTab);
            app.ColorizeSwitchLabel.Text = 'Colorize';
            app.ColorizeBlueSwitch = uiswitch(app.RefChannelTab, 'slider');
            app.ColorizeBlueSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorizeBlueSwitchValueChanged, true);
            app.AddArbitraryROIsButton = uibutton(app.RefChannelTab, 'push');
            app.AddArbitraryROIsButton.ButtonPushedFcn = createCallbackFcn(app, @AddArbitraryROIsButtonPushed, true);
            app.AddArbitraryROIsButton.Text = 'Add Arbitrary ROIs';
            app.MaxBlueSizePixelsSliderLabel = uilabel(app.RefChannelTab);
            app.MaxBlueSizePixelsSliderLabel.Text = 'Max Blue Size (Pixels)';
            app.MaxBlueSizePixelsSlider = uislider(app.RefChannelTab);
            app.MaxBlueSizePixelsSlider.Limits = [200 400];
            app.MaxBlueSizePixelsSlider.Value = 300;
            app.BlueThresholdPass2SliderLabel = uilabel(app.RefChannelTab);
            app.BlueThresholdPass2SliderLabel.Text = 'BlueThreshold Pass 2';
            app.BlueThresholdPass2Slider = uislider(app.RefChannelTab);
            app.BlueThresholdPass2Slider.Limits = [0 150];
            app.BlueThresholdPass2Slider.Value = 75;
            app.AdditionalChannelsTab = uitab(app.TabGroup);
            app.AdditionalChannelsTab.Title = 'Additional Channels';
            app.GreenThresholdSliderLabel = uilabel(app.AdditionalChannelsTab);
            app.GreenThresholdSliderLabel.Text = 'Green Threshold';
            app.GreenThresholdSlider = uislider(app.AdditionalChannelsTab);
            app.GreenThresholdSlider.Value = 50;
            app.GreenSizePixelsSliderLabel = uilabel(app.AdditionalChannelsTab);
            app.GreenSizePixelsSliderLabel.Text = 'Green Size (Pixels)';
            app.GreenSizePixelsSlider = uislider(app.AdditionalChannelsTab);
            app.GreenSizePixelsSlider.Value = 70;
            app.RedThresholdLabel = uilabel(app.AdditionalChannelsTab);
            app.RedThresholdLabel.Text = 'Red Threshold';
            app.RedThresholdSlider = uislider(app.AdditionalChannelsTab);
            app.RedThresholdSlider.Value = 30;
            app.RedSizePixelsSliderLabel = uilabel(app.AdditionalChannelsTab);
            app.RedSizePixelsSliderLabel.Text = 'Red Size (Pixels)';
            app.RedSizePixelsSlider = uislider(app.AdditionalChannelsTab);
            app.RedSizePixelsSlider.Value = 70;
            app.GreenThresholdSwitch = uiswitch(app.AdditionalChannelsTab, 'slider');
            app.GreenThresholdSwitch.Value = 'On';
            app.RedThresholdSwitch = uiswitch(app.AdditionalChannelsTab, 'slider');
            app.RedThresholdSwitch.Value = 'On';
            app.PopulationBlueGreenRedLabel = uilabel(app.AdditionalChannelsTab);
            app.PopulationBlueGreenRedLabel.Text = 'Population (Blue, Green, Red)';
            app.ExportROIsButton = uibutton(app.AdditionalChannelsTab, 'push');
            app.ExportROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportROIsButtonPushed, true);
            app.ExportROIsButton.Text = 'Export ROIs';
            app.ToggleROIButton = uibutton(app.AdditionalChannelsTab, 'push');
            app.ToggleROIButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleROIButtonPushed, true);
            app.ToggleROIButton.Text = 'Toggle ROI';
            app.RemoveROIsButton = uibutton(app.AdditionalChannelsTab, 'push');
            app.RemoveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveROIsButtonPushed, true);
            app.RemoveROIsButton.Text = 'Remove ROIs';
            app.ProcessButton = uibutton(app.AdditionalChannelsTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process!';
            app.BlueLabel = uilabel(app.AdditionalChannelsTab);
            app.BlueLabel.Text = 'Blue';
            app.GreenLabel = uilabel(app.AdditionalChannelsTab);
            app.GreenLabel.Text = 'Green';
            app.DisplayControlsPanel = uipanel(app.AdditionalChannelsTab);
            app.DisplayControlsPanel.Title = 'Display Controls';
            app.UpdateDisplayButton = uibutton(app.DisplayControlsPanel, 'push');
            app.UpdateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplayButtonPushed, true);
            app.UpdateDisplayButton.Text = 'Update Display';
            app.ShowChannelGreenSwitch = uiswitch(app.DisplayControlsPanel, 'slider');
            app.ShowChannelGreenSwitch.Value = 'On';
            app.ShowChannelRedSwitch = uiswitch(app.DisplayControlsPanel, 'slider');
            app.ShowChannelRedSwitch.Value = 'On';
            app.ShowROIGreenSwitch = uiswitch(app.DisplayControlsPanel, 'slider');
            app.ShowROIGreenSwitch.Value = 'On';
            app.ShowROIRedSwitch = uiswitch(app.DisplayControlsPanel, 'slider');
            app.ShowROIRedSwitch.Value = 'On';
            app.BlueDisplayLabel = uilabel(app.DisplayControlsPanel);
            app.BlueDisplayLabel.Text = 'Blue';
            app.GreenDisplayLabel = uilabel(app.DisplayControlsPanel);
            app.GreenDisplayLabel.Text = 'Green';
            app.RedDisplayLabel = uilabel(app.DisplayControlsPanel);
            app.RedDisplayLabel.Text = 'Red';
            app.ShowROIsSwitch_2Label = uilabel(app.DisplayControlsPanel);
            app.ShowROIsSwitch_2Label.Text = 'Show ROIs';
            app.ShowROIBlueSwitch = uiswitch(app.DisplayControlsPanel, 'slider');
            app.ShowROIBlueSwitch.Value = 'On';
            app.ShowChannelSwitchLabel = uilabel(app.DisplayControlsPanel);
            app.ShowChannelSwitchLabel.Text = 'Show Channel';
            app.ShowChannelBlueSwitch = uiswitch(app.DisplayControlsPanel, 'slider');
            app.ShowChannelBlueSwitch.Value = 'On';
            app.RedLabel = uilabel(app.AdditionalChannelsTab);
            app.RedLabel.Text = 'Red';
            app.ChangeRangesButton = uibutton(app.AdditionalChannelsTab, 'push');
            app.ChangeRangesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeRangesButtonPushed, true);
            app.ChangeRangesButton.Text = 'Change Ranges';
            app.ColorizeSwitch_2Label = uilabel(app.AdditionalChannelsTab);
            app.ColorizeSwitch_2Label.Text = 'Colorize';
            app.ColorizeAllSwitch = uiswitch(app.AdditionalChannelsTab, 'slider');
            app.ColorizeAllSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorizeAllSwitchValueChanged, true);
            % Show the figure after all components are created
            app.CellCountingGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellCountingGUI_rev4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CellCountingGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellCountingGUIUIFigure)
        end
    end
end